classdef flightPlanApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MainAppTab               matlab.ui.container.Tab
        GridLayout               matlab.ui.container.GridLayout
        TabGroup2                matlab.ui.container.TabGroup
        GeographicPlotTab        matlab.ui.container.Tab
        CartesianPlotTab         matlab.ui.container.Tab
        UIAxes                   matlab.ui.control.UIAxes
        optionGrid               matlab.ui.container.GridLayout
        photoNum                 matlab.ui.control.NumericEditField
        ofPhotosEditFieldLabel   matlab.ui.control.Label
        loadGroundCoordinates    matlab.ui.control.Button
        minAltitude              matlab.ui.control.NumericEditField
        maxDisplacement          matlab.ui.control.EditField
        offsetBias               matlab.ui.control.Slider
        straightBias             matlab.ui.control.Slider
        speed                    matlab.ui.control.NumericEditField
        flightPathButton         matlab.ui.control.Button
        horizontalFOV            matlab.ui.control.NumericEditField
        verticalFOV              matlab.ui.control.NumericEditField
        overlap                  matlab.ui.control.NumericEditField
        OverlapEditFieldLabel    matlab.ui.control.Label
        createPhotoProjection    matlab.ui.control.Button
        VerticalFOVLabel         matlab.ui.control.Label
        HorizontalFOVLabel       matlab.ui.control.Label
        SpeedmsEditFieldLabel    matlab.ui.control.Label
        StraightBiasSliderLabel  matlab.ui.control.Label
        OffsetBiasSliderLabel    matlab.ui.control.Label
        MaxDistancemLabel        matlab.ui.control.Label
        MinAltmLabel             matlab.ui.control.Label
        convert2qgc              matlab.ui.control.Button
    end

%% Creating Public Variables
    properties (Access = public)
        GroundCoords           double        % Nx3 matrix [Gx, Gy, Gz] : ground coordinates
        FlightPlan             double        % Nx3 matrix : raw flight plan coordinates
        stats                  struct        % Structure : raw statistics (angle, displacement)
        ProcessedData          table         % Table : processed flight path + gimbal angles data
        captureIndices         double        % Vector : stores indices of capture points
        PhotoData              double        % Photo Data from projection calculation
        qgc                    double        % Data for conversion to .plan file
        latlon                 double        % 
        geoAxesPlot            matlab.graphics.axis.GeographicAxes
        photoData              struct        % Photo corners
        origin                 double        % Origin of cartesian in geographic
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%% Initial Set-Up
            clc
            close("all")

            % Centre the app on the screen
            movegui(app.UIFigure,"center")

%% Check if required MATLAB toolboxes are installed
    requiredToolboxes = {'Automated Driving Toolbox','Mapping Toolbox'};

    % Get the list of installed toolboxes
    installedToolboxes = matlab.addons.installedAddons;
    installedNames = {installedToolboxes.Name};
    
    % Check for missing toolboxes
    missingToolboxes = setdiff(requiredToolboxes, installedNames{1,1});
    
    if isempty(missingToolboxes)
    else
        for i = 1:length(missingToolboxes)
            alert = "Required Toolbox not Installed: " + requiredToolboxes;
            uialert(app.UIFigure, alert, 'Error','Icon','error');
        end
    end

%% Add functions folder to current path:
    appFolder = fileparts(mfilename('fullpath')); % Get the app's folder
    subFolder = fullfile(appFolder, 'Functions'); % Define relative path
    addpath(subFolder);

%% Create Geoaxes Plot
    app.geoAxesPlot = geoaxes(app.GeographicPlotTab,"Basemap","satellite");
    hold(app.geoAxesPlot,"on")
    latlim = [53.31500796665545 53.3221560993851];
    lonlim = [-4.033111429740774 -4.018773929947079];
    geolimits(app.geoAxesPlot,latlim,lonlim)


        end

        % Button pushed function: loadGroundCoordinates
        function loadGroundCoords(app, event)
%% Load Ground Coordinate Functions
            [file, path] = uigetfile('*.csv', 'Select Ground Coordinate File'); % Asks user to select csv file
            
            if isequal(file,0)
                return;
            end

            fullFile = fullfile(path, file);
            T = readtable(fullFile); % Reads input file

            if all(ismember({'Gx','Gy','Gz'}, T.Properties.VariableNames)) % Checks for correct columns in .csv file
                app.GroundCoords = [T.Gx, T.Gy, T.Gz]; % Stores ground coordinates
                uialert(app.UIFigure, 'Ground data loaded successfully.', 'Success','Icon','success'); % Indicates file is loaded 
            else
                uialert(app.UIFigure, 'CSV must contain Gx, Gy, Gz columns.', 'Error'); % Indicates file is not loaded
            end

        end

        % Button pushed function: flightPathButton
        function genFlightPath(app, event)
%% Generate Flight Path Function
            % Check whether ground coordinates have been loaded
            if isempty(app.GroundCoords)
                errordlg('Please load ground coordinates first.', 'Error');
                return;
            end

            % Store user input values
            biasVal = app.offsetBias.Value; % Offset bias value
            straightVal = app.straightBias.Value; % Straight flight bias value
            minAlt = app.minAltitude.Value; % Minimum altitude value
            maxDisp = app.maxDisplacement.Value; % Maximum displacemennt value
            UAVspeed = app.speed.Value;

            % Calculating the slope of the ground (deg)
            [slopeVal] = calculateSlope(app);

            % Generate raw flight plan and stats
            [app.FlightPlan, app.stats] = generateFlightPath(app,app.GroundCoords, biasVal, straightVal, slopeVal, minAlt, maxDisp, UAVspeed);
            
            % Process the raw flight data
            [app.ProcessedData] = processFlightData(app);

            % Plot flight path on cartesian map
            plotFlightPath(app);

            % Converting coordinates to latitude and longitude
            [app.latlon,app.origin] = convert2latlon(app);

            % Plot flight path on geo map
            plotGeo(app);

        end

        % Button pushed function: createPhotoProjection
        function projection(app, event)
%% Generate Photo Projection
        % Check whether flight path has been generated
        if isempty(app.ProcessedData)
            uialert(app.UIFigure, 'Please generate flight path first.', 'Error');
            return;
        end

        % Calculate number of photos required:
        [app.stats.photoNum] = calculatePhotoNum(app);

        % Function to calculate and plot photo projection on cartesian map
        photoProjection(app);

        % Function to plot photo projection on geo map
        photoProjectionGeo(app)

        % Showing the number of photos in UI:
        app.photoNum.Value = app.stats.photoNum;

        end

        % Button pushed function: convert2qgc
        function convert(app, event)
%% Convert to a QGroundControl .plan mission
            % Check whether photo projection has been made
            if isempty(app.captureIndices)
                uialert(app.UIFigure, 'No capture points found. Plot projection first.', 'Error');
                return;
            end

            % Convert to .plan mission and save it
            convert2plan(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainAppTab = uitab(app.TabGroup);
            app.MainAppTab.Title = 'Main App';
            app.GridLayout = uigridlayout(app.MainAppTab);
            app.optionGrid = uigridlayout(app.GridLayout);
            app.convert2qgc = uibutton(app.optionGrid, 'push');
            app.convert2qgc.ButtonPushedFcn = createCallbackFcn(app, @convert, true);
            app.convert2qgc.Text = 'Convert to QGC';
            app.MinAltmLabel = uilabel(app.optionGrid);
            app.MinAltmLabel.Text = 'Minimum Altitude (m)';
            app.MaxDistancemLabel = uilabel(app.optionGrid);
            app.MaxDistancemLabel.Text = 'Maximum Distance (m)';
            app.OffsetBiasSliderLabel = uilabel(app.optionGrid);
            app.OffsetBiasSliderLabel.Text = 'Offset Bias';
            app.StraightBiasSliderLabel = uilabel(app.optionGrid);
            app.StraightBiasSliderLabel.Text = 'Straight Bias';
            app.SpeedmsEditFieldLabel = uilabel(app.optionGrid);
            app.SpeedmsEditFieldLabel.Text = 'Speed (m/s)';
            app.HorizontalFOVLabel = uilabel(app.optionGrid);
            app.HorizontalFOVLabel.Text = 'Horizontal FOV (°)';
            app.VerticalFOVLabel = uilabel(app.optionGrid);
            app.VerticalFOVLabel.Text = 'Vertical FOV (°)';
            app.createPhotoProjection = uibutton(app.optionGrid, 'push');
            app.createPhotoProjection.ButtonPushedFcn = createCallbackFcn(app, @projection, true);
            app.createPhotoProjection.Text = 'Generate Photo Projection';
            app.OverlapEditFieldLabel = uilabel(app.optionGrid);
            app.OverlapEditFieldLabel.Text = '% Overlap';
            app.overlap = uieditfield(app.optionGrid, 'numeric');
            app.overlap.Limits = [50 90];
            app.overlap.Value = 70;
            app.verticalFOV = uieditfield(app.optionGrid, 'numeric');
            app.verticalFOV.Value = 40;
            app.horizontalFOV = uieditfield(app.optionGrid, 'numeric');
            app.horizontalFOV.Value = 60;
            app.flightPathButton = uibutton(app.optionGrid, 'push');
            app.flightPathButton.ButtonPushedFcn = createCallbackFcn(app, @genFlightPath, true);
            app.flightPathButton.Text = 'Generate Flight Path';
            app.speed = uieditfield(app.optionGrid, 'numeric');
            app.speed.Value = 2;
            app.straightBias = uislider(app.optionGrid);
            app.straightBias.Limits = [0 1];
            app.straightBias.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.straightBias.MinorTicks = [];
            app.straightBias.Value = 0.3;
            app.offsetBias = uislider(app.optionGrid);
            app.offsetBias.Limits = [0.2 1];
            app.offsetBias.MajorTicks = [0.2 0.4 0.6 0.8 1];
            app.offsetBias.MinorTicks = [];
            app.offsetBias.Value = 0.5;
            app.maxDisplacement = uieditfield(app.optionGrid, 'text');
            app.maxDisplacement.Value = '60';
            app.minAltitude = uieditfield(app.optionGrid, 'numeric');
            app.minAltitude.Value = 30;
            app.loadGroundCoordinates = uibutton(app.optionGrid, 'push');
            app.loadGroundCoordinates.ButtonPushedFcn = createCallbackFcn(app, @loadGroundCoords, true);
            app.loadGroundCoordinates.Text = 'Load Ground Coordinates';
            app.ofPhotosEditFieldLabel = uilabel(app.optionGrid);
            app.ofPhotosEditFieldLabel.Text = '# of Photos:';
            app.photoNum = uieditfield(app.optionGrid, 'numeric');
            app.photoNum.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.GeographicPlotTab = uitab(app.TabGroup2);
            app.GeographicPlotTab.Title = 'Geographic Plot';
            app.CartesianPlotTab = uitab(app.TabGroup2);
            app.CartesianPlotTab.Title = 'Cartesian Plot';
            app.UIAxes = uiaxes(app.CartesianPlotTab);
            xlabel(app.UIAxes, 'x (m)')
            ylabel(app.UIAxes, 'y (m)')
            zlabel(app.UIAxes, 'z (m)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = flightPlanApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end